classdef Temperature_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup2                    matlab.ui.container.TabGroup
        TemperaturecheckTab          matlab.ui.container.Tab
        ActualTemperatureCEditFieldLabel  matlab.ui.control.Label
        ActualTemperatureCEditField  matlab.ui.control.NumericEditField
        GetactualtemperatureButton   matlab.ui.control.Button
        StopandcleardataButton       matlab.ui.control.Button
        StopandsavedataButton        matlab.ui.control.Button
        ControltemperaturecheckTab   matlab.ui.container.Tab
        TCGauge                      matlab.ui.control.Gauge
        TCGaugeLabel                 matlab.ui.control.Label
        GettemperatureButton         matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        ArduinoConnectionTab         matlab.ui.container.Tab
        COMPortsDropDown             matlab.ui.control.DropDown
        COMPortsDropDownLabel        matlab.ui.control.Label
        ArduinoDisconnectionButton   matlab.ui.control.Button
        ArduinoConnectionButton      matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        OpenLoopSystemAnalysisTab    matlab.ui.container.Tab
        StopOpenLoopButton           matlab.ui.control.Button
        StartOpenLoopButton          matlab.ui.control.Button
        ConstantPWMEditField         matlab.ui.control.NumericEditField
        ConstantPWMEditFieldLabel    matlab.ui.control.Label
        ControlSchemeTab             matlab.ui.container.Tab
        StopButton                   matlab.ui.control.StateButton
        StartButton                  matlab.ui.control.StateButton
        GPCConstraintsButton         matlab.ui.control.Button
        PIAntiWindUpButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        a        % Arduino 
        T        % Control Design
        Temp     % Description
        time     % Description
        TempU    % Description
        Begin_CT % Description
        TF_CT    % Description
        PWM      % Description
        T_OL     % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMPortsDropDown.Items=seriallist;    %Returns the list of all serial ports on the system.
            app.Lamp.Color='r';                       %Set digital LED color to red. 
            app.StopandcleardataButton.Visible='off'  %Make button unable to be seen. 
            app.StopandsavedataButton.Visible='off'   %Make button unable to be seen.
            app.StopOpenLoopButton.Visible='off'      %Make button unable to be seen.
            app.StopButton.Visible='off'              %Make button unable to be seen.
        end

        % Button pushed function: PIAntiWindUpButton
        function PIAntiWindUpButtonPushed(app, event)
            addpath("Control_PI_AntiWindup\")        %Add the PI-AntiWindUp control files to the path. 
            app.T = TemperatureM_PI(app.a);          %Call TemperatureM_PI function.
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;         
            app.StopButton.Visible='on'             %Make button able to be seen. 
            app.T.start;                            %Start control function chosed.  
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;            
            app.T.stop;                            %Stop Control Loop
            pin = 'D9';                            %Name Digital Pin D9
            writePWMDutyCycle(app.a,pin,0);        %Send PWM to Digital Pin 9 to 0. 
            Plot_Save_Data                         %Call Plot_Save_Data Function.
        end

        % Button pushed function: GettemperatureButton
        function GettemperatureButtonPushed(app, event)
        if ~isempty(app.a)
  
           
            while (app.StopButton.Value==false) 
                app.TempU = evalin("base",'TF');
                app.TCGauge.Value = app.TempU;    %Show temperature value on the Gauge.
            end
                       
        else  
           msgbox('Arduino is not connected, please push Arduino Connection button before.')
   
        end    
        end

        % Button pushed function: ArduinoConnectionButton
        function ArduinoConnectionButtonPushed(app, event)
           com_port=app.COMPortsDropDown.Value;
            
            if ~isempty(app.a) 
                msgbox('Arduino already connected');
            end
            
            try
                app.a=arduino(com_port,'Uno');
                disp(app.a.Port)
                app.Lamp.Color='g';
            catch
                errordlg('Arduino connection failed');
            end
             
        end

        % Button pushed function: ArduinoDisconnectionButton
        function ArduinoDisconnectionButtonPushed(app, event)
            if ~isempty(app.a)
                fclose(serial(app.a.Port)); %Create a serial object with the port Arduino is connected to it and close it
                clear app.a;
                app.Lamp.Color='r';
                close all;
                clear all;
                
            else
                msgbox('Arduino is not connected')
            end
        end

        % Button pushed function: GetactualtemperatureButton
        function GetactualtemperatureButtonPushed(app, event)
         if ~isempty(app.a)
            
            if app.StartButton.Value==false
            app.StopandcleardataButton.Visible='on'
            app.StopandsavedataButton.Visible='on'
                
                
            addpath("Continuous_Temperature\")
            app.Begin_CT=GetTemperature(app.a)
            app.Begin_CT.start
            
            while (app.StartButton.Value==false)
                app.TF_CT = evalin("base",'TF_CT');
                app.ActualTemperatureCEditField.Value = app.TF_CT; 
            end


            else
             msgbox('This option just works if there is no control system runing')
            
            end 
            
         else
             
             msgbox('Arduino is not connected, please push Arduino Connection button before.')
         
         end
        end

        % Button pushed function: StartOpenLoopButton
        function StartOpenLoopButtonPushed(app, event)
        addpath("Open Loop\")

           if ~isempty(app.a) 
                    app.StopOpenLoopButton.Visible='on'          
                    app.T_OL=TemperatureM(app.a,app.ConstantPWMEditField.Value);
                    app.T_OL.start
            
           else
        
             msgbox('Arduino is not connected, please push Arduino Connection button before.')
     
           end
        end

        % Button pushed function: StopOpenLoopButton
        function StopOpenLoopButtonPushed(app, event)
            app.T_OL.stop      
            pin = 'D9';
            writePWMDutyCycle(app.a,pin,0);
            Plot_Save_Data 
        end

        % Button pushed function: GPCConstraintsButton
        function GPCConstraintsButtonPushed(app, event)
            addpath("Control_GPC/GPC_Constraints")
            [Bd,Ad,N,Nu,Ql,Qd,F,G,Gif]=GPC_SystemData 
            app.T = TemperatureM_GPC(app.a,Bd,Ad,N,Nu,Ql,Qd,F,G,Gif);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear app;
            close all;
            clc;
        end

        % Button pushed function: StopandcleardataButton
        function StopandcleardataButtonPushed(app, event)
              app.Begin_CT.stop;
              evalin ('base','clear all');
              app.StopandcleardataButton.Visible='off'
              app.StopandsavedataButton.Visible='off'
        end

        % Button pushed function: StopandsavedataButton
        function StopandsavedataButtonPushed(app, event)
            app.Begin_CT.stop;
            Plot_Save_Data_Continuous
            app.StopandsavedataButton.Visible='off'
            app.StopandcleardataButton.Visible='off'
        end

        % Button down function: TemperaturecheckTab
        function TemperaturecheckTabButtonDown(app, event)
            app.TabGroup.SelectedTab = app.ArduinoConnectionTab
        end

        % Button down function: OpenLoopSystemAnalysisTab
        function OpenLoopSystemAnalysisTabButtonDown(app, event)
            app.TabGroup2.SelectedTab = app.ControltemperaturecheckTab
        end

        % Button down function: ControlSchemeTab
        function ControlSchemeTabButtonDown(app, event)
            app.TabGroup2.SelectedTab = app.ControltemperaturecheckTab
        end

        % Button down function: ArduinoConnectionTab
        function ArduinoConnectionTabButtonDown(app, event)
            app.TabGroup2.SelectedTab = app.TemperaturecheckTab
        end

        % Button down function: ControltemperaturecheckTab
        function ControltemperaturecheckTabButtonDown(app, event)
            app.TabGroup.SelectedTab = app.ControlSchemeTab
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArduinoConnectionTab = uitab(app.TabGroup);
            app.ArduinoConnectionTab.Title = 'Arduino Connection';
            app.ArduinoConnectionTab.ButtonDownFcn = createCallbackFcn(app, @ArduinoConnectionTabButtonDown, true);
            app.Lamp = uilamp(app.ArduinoConnectionTab);
            app.ArduinoConnectionButton = uibutton(app.ArduinoConnectionTab, 'push');
            app.ArduinoConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @ArduinoConnectionButtonPushed, true);
            app.ArduinoConnectionButton.Text = 'Arduino Connection';
            app.ArduinoDisconnectionButton = uibutton(app.ArduinoConnectionTab, 'push');
            app.ArduinoDisconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @ArduinoDisconnectionButtonPushed, true);
            app.ArduinoDisconnectionButton.Text = 'Arduino Disconnection';
            app.COMPortsDropDownLabel = uilabel(app.ArduinoConnectionTab);
            app.COMPortsDropDownLabel.Text = 'COM Ports';
            app.COMPortsDropDown = uidropdown(app.ArduinoConnectionTab);
            app.OpenLoopSystemAnalysisTab = uitab(app.TabGroup);
            app.OpenLoopSystemAnalysisTab.Title = 'Open Loop System Analysis';
            app.OpenLoopSystemAnalysisTab.ButtonDownFcn = createCallbackFcn(app, @OpenLoopSystemAnalysisTabButtonDown, true);
            app.ConstantPWMEditFieldLabel = uilabel(app.OpenLoopSystemAnalysisTab);
            app.ConstantPWMEditFieldLabel.Text = 'Constant PWM';
            app.ConstantPWMEditField = uieditfield(app.OpenLoopSystemAnalysisTab, 'numeric');
            app.ConstantPWMEditField.Limits = [0 1];
            app.StartOpenLoopButton = uibutton(app.OpenLoopSystemAnalysisTab, 'push');
            app.StartOpenLoopButton.ButtonPushedFcn = createCallbackFcn(app, @StartOpenLoopButtonPushed, true);
            app.StartOpenLoopButton.Text = 'Start Open Loop';
            app.StopOpenLoopButton = uibutton(app.OpenLoopSystemAnalysisTab, 'push');
            app.StopOpenLoopButton.ButtonPushedFcn = createCallbackFcn(app, @StopOpenLoopButtonPushed, true);
            app.StopOpenLoopButton.Text = 'Stop Open Loop';
            app.ControlSchemeTab = uitab(app.TabGroup);
            app.ControlSchemeTab.Title = 'Control Scheme';
            app.ControlSchemeTab.ButtonDownFcn = createCallbackFcn(app, @ControlSchemeTabButtonDown, true);
            app.PIAntiWindUpButton = uibutton(app.ControlSchemeTab, 'push');
            app.PIAntiWindUpButton.ButtonPushedFcn = createCallbackFcn(app, @PIAntiWindUpButtonPushed, true);
            app.PIAntiWindUpButton.Text = 'PI-AntiWind-Up';
            app.GPCConstraintsButton = uibutton(app.ControlSchemeTab, 'push');
            app.GPCConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @GPCConstraintsButtonPushed, true);
            app.GPCConstraintsButton.Text = 'GPC Constraints';
            app.StartButton = uibutton(app.ControlSchemeTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlSchemeTab, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TemperaturecheckTab = uitab(app.TabGroup2);
            app.TemperaturecheckTab.Title = 'Temperature check';
            app.TemperaturecheckTab.ButtonDownFcn = createCallbackFcn(app, @TemperaturecheckTabButtonDown, true);
            app.StopandsavedataButton = uibutton(app.TemperaturecheckTab, 'push');
            app.StopandsavedataButton.ButtonPushedFcn = createCallbackFcn(app, @StopandsavedataButtonPushed, true);
            app.StopandsavedataButton.Text = 'Stop and save data';
            app.StopandcleardataButton = uibutton(app.TemperaturecheckTab, 'push');
            app.StopandcleardataButton.ButtonPushedFcn = createCallbackFcn(app, @StopandcleardataButtonPushed, true);
            app.StopandcleardataButton.Text = 'Stop and clear data';
            app.GetactualtemperatureButton = uibutton(app.TemperaturecheckTab, 'push');
            app.GetactualtemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @GetactualtemperatureButtonPushed, true);
            app.GetactualtemperatureButton.Text = 'Get actual temperature ';
            app.ActualTemperatureCEditField = uieditfield(app.TemperaturecheckTab, 'numeric');
            app.ActualTemperatureCEditFieldLabel = uilabel(app.TemperaturecheckTab);
            app.ActualTemperatureCEditFieldLabel.Text = 'Actual Temperature [°C]';
            app.ControltemperaturecheckTab = uitab(app.TabGroup2);
            app.ControltemperaturecheckTab.Title = 'Control temperature check';
            app.ControltemperaturecheckTab.ButtonDownFcn = createCallbackFcn(app, @ControltemperaturecheckTabButtonDown, true);
            app.GettemperatureButton = uibutton(app.ControltemperaturecheckTab, 'push');
            app.GettemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @GettemperatureButtonPushed, true);
            app.GettemperatureButton.Text = 'Get temperature';
            app.TCGaugeLabel = uilabel(app.ControltemperaturecheckTab);
            app.TCGaugeLabel.Text = 'T °C';
            app.TCGauge = uigauge(app.ControltemperaturecheckTab, 'circular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Temperature_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end